classdef lf < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        LeftPanel                matlab.ui.container.Panel
        apertureSliderLabel      matlab.ui.control.Label
        apertureSlider           matlab.ui.control.Slider
        focalsurfaceSliderLabel  matlab.ui.control.Label
        focalsurfaceSlider       matlab.ui.control.Slider
        cameraXSliderLabel       matlab.ui.control.Label
        cameraXSlider            matlab.ui.control.Slider
        cameraYLabel             matlab.ui.control.Label
        cameraYSlider            matlab.ui.control.Slider
        cameraZSliderLabel       matlab.ui.control.Label
        cameraZSlider            matlab.ui.control.Slider
        RightPanel               matlab.ui.container.Panel
        UIAxes                   matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %%  x-y 轴位于光轴上，即uv-st平面中心
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        % st平面
        img_width = 320;
        img_height = 240;
        imgs = zeros(16, 16, 240, 320, 3);  % 16*16=u,v  320 = s, 240 = t
        st_z = 0;
        color_chanel = 3;
        
        % uv平面/微透镜阵列
        uv_z = 1;
        aperture = 0; % 默认光圈为0(针孔相机)
        focal_plane_z = 1; % 针孔相机没有焦距？ 初始化与uv平面重合，无意义
        lens_location = zeros(16, 16, 2); % 每个透镜的实际x、y位置， 等于st中对应传感器的中心
        
        % 虚拟相机
        camera_x = 0; % x位于相机中心
        camera_y = 0; % y位于相机中心
        camera_z = 10;
        camera_width = 320 / (1); % 若想减小像素的大小，等同于使用更大的相机
        camera_height = 240/ (1);
    end
    
    methods (Access = private)
        
        function img = img_show(app)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %% 根据光圈、focal plane、相机位置， 计算输出图像
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            img = zeros(app.camera_width, app.camera_height, app.color_chanel);
            
            for camera_y = 1:app.camera_height
                for camera_x = 1:app.camera_width
                    % 遍历相机的每个像素
                    for v = 1:16
                        for u = 1:16 
                            % 遍历uv/微透镜阵列
                            
                            % 追踪光线的实际位置
                            
                            
%                             img(camera_x, camera_y, app.color_chanel) = 


                        end
                    end
                end
            end


            % test
            % img = reshape(app.imgs(1, 1, :, :, :), app.img_height, app.img_width, 3);
            
           
        end
        
        function [] = init_ui(app)
            % 初始化ui参数
            app.apertureSlider.Value = app.aperture; % 初始camera 阵列的光圈
            app.focalsurfaceSlider.Value = app.focal_plane_z; % 初始 focal plane
            app.cameraXSlider.Value = app.camera_x; % 相机x 
            app.cameraYSlider.Value = app.camera_y; % 相机y
            app.cameraZSlider.Value = app.camera_z; % 相机z
        end
        
        function [] = load_imgs(app)
            % 读取图片
            img_path_list = dir('*.bmp');
            for i = 1:16
                for j=1:16
                    app.imgs(i, j, :, :, :) = rescale( ...
                        imread(string(img_path_list((i-1)*16+j).name)), ...
                        0, 1); 
                end
            end
        end
        
        function [] = refresh_screen(app)
            % 生成新的图像并输出至屏幕
            img = app.img_show();
            imshow(img, 'Parent',app.UIAxes);
        end
       
        function [] = init_para(app)
            % 以st阵列中每块像素的左下角计算lens_location
            for i = -8:7
                for j = 7:-1:-8
                    app.lens_location(17-(j+9),i+9,:) = [i * app.img_width + app.img_width / 2, j * app.img_height + app.img_height / 2 ];
                end
            end
            disp(app.lens_location)
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.init_para(); % 初始化参数
            app.init_ui(); % 使用参数默认值初始化滚动条默认值
            app.load_imgs(); % 读取256张bmp图片
            app.refresh_screen(); % 输出图像
            
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {209, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Value changed function: apertureSlider
        function apertureSliderValueChanged(app, event)
            % 改变光圈
            app.aperture = app.apertureSlider.Value;

            % 刷新图像
            app.refresh_screen();
        end

        % Value changed function: focalsurfaceSlider
        function focalsurfaceSliderValueChanged(app, event)
            % 改变焦距
            app.focal_plane_z = app.focalsurfaceSlider.Value;

            % 刷新图像
            app.refresh_screen();
        end

        % Value changed function: cameraXSlider
        function cameraXSliderValueChanged(app, event)
            % 改变相机x
            app.camera_x = app.cameraXSlider.Value;
            
            % 刷新图像
            app.refresh_screen();
        end

        % Value changed function: cameraYSlider
        function cameraYSliderValueChanged(app, event)
            % 改变相机y
            app.camera_y = app.cameraYSlider.Value;
            
            % 刷新图像
            app.refresh_screen();
        end

        % Value changed function: cameraZSlider
        function cameraZSliderValueChanged(app, event)
            % 改变相机z
            app.camera_z = app.cameraZSlider.Value;
            
            % 刷新图像
            app.refresh_screen();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.apertureSliderLabel = uilabel(app.LeftPanel);
            app.apertureSliderLabel.Text = 'aperture';
            app.apertureSlider = uislider(app.LeftPanel);
            app.apertureSlider.Limits = [0 10];
            app.apertureSlider.ValueChangedFcn = createCallbackFcn(app, @apertureSliderValueChanged, true);
            app.apertureSlider.Tooltip = {''};
            app.focalsurfaceSliderLabel = uilabel(app.LeftPanel);
            app.focalsurfaceSliderLabel.Text = 'focal surface';
            app.focalsurfaceSlider = uislider(app.LeftPanel);
            app.focalsurfaceSlider.Limits = [0 1];
            app.focalsurfaceSlider.ValueChangedFcn = createCallbackFcn(app, @focalsurfaceSliderValueChanged, true);
            app.cameraXSliderLabel = uilabel(app.LeftPanel);
            app.cameraXSliderLabel.Text = 'camera X';
            app.cameraXSlider = uislider(app.LeftPanel);
            app.cameraXSlider.Limits = [-100 100];
            app.cameraXSlider.ValueChangedFcn = createCallbackFcn(app, @cameraXSliderValueChanged, true);
            app.cameraYLabel = uilabel(app.LeftPanel);
            app.cameraYLabel.Text = 'camera Y';
            app.cameraYSlider = uislider(app.LeftPanel);
            app.cameraYSlider.Limits = [-100 100];
            app.cameraYSlider.ValueChangedFcn = createCallbackFcn(app, @cameraYSliderValueChanged, true);
            app.cameraZSliderLabel = uilabel(app.LeftPanel);
            app.cameraZSliderLabel.Text = 'camera Z';
            app.cameraZSlider = uislider(app.LeftPanel);
            app.cameraZSlider.Limits = [-100 100];
            app.cameraZSlider.ValueChangedFcn = createCallbackFcn(app, @cameraZSliderValueChanged, true);
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, '320 x 240 virtual camera view')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lf

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end